classdef Interfaz_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Z2CEditFieldLabel           matlab.ui.control.Label
        Y2CEditFieldLabel           matlab.ui.control.Label
        X2CEditFieldLabel           matlab.ui.control.Label
        Z2CEditField                matlab.ui.control.NumericEditField
        Y2CEditField                matlab.ui.control.NumericEditField
        X2CEditField                matlab.ui.control.NumericEditField
        AplicaTrayectoriaButton     matlab.ui.control.Button
        Z2EditFieldLabel            matlab.ui.control.Label
        Y2EditFieldLabel            matlab.ui.control.Label
        X2EditFieldLabel            matlab.ui.control.Label
        Z2EditField                 matlab.ui.control.NumericEditField
        Y2EditField                 matlab.ui.control.NumericEditField
        X2EditField                 matlab.ui.control.NumericEditField
        VelServoEditField           matlab.ui.control.NumericEditField
        VelServoEditFieldLabel      matlab.ui.control.Label
        VelMotorEditField           matlab.ui.control.NumericEditField
        VelMotorEditFieldLabel      matlab.ui.control.Label
        AutoresChristianSalanDanielHernndezDavidRedondoLabel  matlab.ui.control.Label
        AutoresChristianSalanDanielDavidRedondoLabel  matlab.ui.control.Label
        ESTADOLamp                  matlab.ui.control.Lamp
        DesconectarButton           matlab.ui.control.Button
        ConectarButton              matlab.ui.control.Button
        PosicionprefabricadaSwitch  matlab.ui.control.Switch
        PosicionprefabricadaLabel   matlab.ui.control.Label
        MEM4Button                  matlab.ui.control.Button
        MEM3Button                  matlab.ui.control.Button
        MEM2Button                  matlab.ui.control.Button
        MEM1Button                  matlab.ui.control.Button
        REPOSOButton                matlab.ui.control.Button
        HOMEButton                  matlab.ui.control.Button
        X1EditFieldLabel_7          matlab.ui.control.Label
        X1EditFieldLabel_6          matlab.ui.control.Label
        X1EditFieldLabel_5          matlab.ui.control.Label
        X1EditFieldLabel_4          matlab.ui.control.Label
        PickPlaceButton             matlab.ui.control.Button
        DesplegarCinematicaDirectaSwitch  matlab.ui.control.ToggleSwitch
        DesplegarCinematicaDirectaSwitchLabel  matlab.ui.control.Label
        Q4Label                     matlab.ui.control.Label
        mm_7                        matlab.ui.control.Label
        mm_6                        matlab.ui.control.Label
        mm_5                        matlab.ui.control.Label
        mm_4                        matlab.ui.control.Label
        mm_3                        matlab.ui.control.Label
        mm_2                        matlab.ui.control.Label
        mm                          matlab.ui.control.Label
        Baud_numEditFieldLabel      matlab.ui.control.Label
        Baud_numEditField           matlab.ui.control.NumericEditField
        PuertoEditFieldLabel        matlab.ui.control.Label
        PuertoEditField             matlab.ui.control.NumericEditField
        PinzaLabel                  matlab.ui.control.Label
        PinzaSwitch                 matlab.ui.control.Switch
        Z1EditField                 matlab.ui.control.NumericEditField
        X1EditField                 matlab.ui.control.NumericEditField
        Y1EditField                 matlab.ui.control.NumericEditField
        trayectoria                 matlab.ui.control.DropDown
        Q5Label                     matlab.ui.control.Label
        Q5Slider                    matlab.ui.control.Slider
        Q4Slider                    matlab.ui.control.Slider
        Q3Label                     matlab.ui.control.Label
        Q3Slider                    matlab.ui.control.Slider
        Q2Label                     matlab.ui.control.Label
        Q2Slider                    matlab.ui.control.Slider
        Q1Label                     matlab.ui.control.Label
        Q1Slider                    matlab.ui.control.Slider
        UITable                     matlab.ui.control.Table
        MTHLabel                    matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditField                  matlab.ui.control.NumericEditField
        XEditField                  matlab.ui.control.NumericEditField
        Y1EditFieldLabel_2          matlab.ui.control.Label
        Z1EditFieldLabel_2          matlab.ui.control.Label
        X1EditFieldLabel_2          matlab.ui.control.Label
        YinvEditField               matlab.ui.control.NumericEditField
        ZinvEditField               matlab.ui.control.NumericEditField
        XinvEditField               matlab.ui.control.NumericEditField
        Y1EditFieldLabel_3          matlab.ui.control.Label
        Z1EditFieldLabel_3          matlab.ui.control.Label
        X1EditFieldLabel_3          matlab.ui.control.Label
        Angulo_TCP                  matlab.ui.control.NumericEditField
        Muestra_Posicion            matlab.ui.control.Label
        Cinematica_Directa          matlab.ui.control.Label
        Z1EditFieldLabel_4          matlab.ui.control.Label
        Cinematica_Directa_2        matlab.ui.control.Label
        Cinematica_Directa_3        matlab.ui.control.Label
        Cinematica_Directa_4        matlab.ui.control.Label
        Cinematica_Directa_5        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        myRobot=creaRobot_v2();
        conectado=0;
        q;%trabaja en radianes
        q_mem1;
        q_mem2;
        q_mem3;
        q_mem4;
        modo='Memorizar';
        Xinv=0.190;
        Yinv=0;
        Zinv=0.350;
        Angulo_tcp=0;%trabaja en grados
        posicion_tornillo=0;%trabaja en mm
        PORTNUM;
        BAUDNUM;
        velocidad_motor;
        velocidad_servo;
    end
    
    methods (Access = private)
        
        function plotRobot(app)
            %Provisional-------------------------------------------------
            figura = figure('visible', "on");
            show(app.myRobot,app.q);
            
            cla (app.UIAxes, 'reset');
            % Se copia por referencia la figura generada en la aplicación
            IF=gca(figura);
            
            copyobj(IF.Children, app.UIAxes);
            app.UIAxes.Projection = 'perspective';
            %app.UIAxes.CameraPosition = campos;
            app.UIAxes.View=[135 0];
            app.UIAxes.CameraUpVector=[1 0 0];
            %app.UIAxes.CameraPosition = [-12.1283, -12.1283, -2.4105];
            
            %Ajustes del graficador
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.ZLabel.String = 'Z';
            app.UIAxes.XLabel.FontSize = 14;
            app.UIAxes.YLabel.FontSize = 14;
            app.UIAxes.ZLabel.FontSize = 14;
            app.UIAxes.XLabel.FontWeight = 'bold';
            app.UIAxes.YLabel.FontWeight = 'bold';
            app.UIAxes.ZLabel.FontWeight = 'bold';
            app.UIAxes.XColor = [1 0 0];
            app.UIAxes.YColor = [0.45 0.68 0.20];
            app.UIAxes.ZColor = [0 0 1];
            app.UIAxes.ZLim=[0 inf];
            app.UIAxes.Title.String= "myRobot";
            
            %Borramos la figura      
            delete(figura)
        end
        
        
        function Actualiza_Directa(app)

            app.plotRobot();
            %MTH->Matriz de transformacion directa en función del
            %valor de los slider
            
            MTH_TCP=getMTH_TCP(app.myRobot,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5));
            
            %Mostramos por pantalla la MTH
            for i=1:3
                MTH_TCP(i,4)=MTH_TCP(i,4)*1000;
            end
            app.UITable.Data(:,:)= MTH_TCP;
            %Actualizamos los valores del tcp que se muestra con el 
            %cinematica directa
            
            app.XEditField.Value=round(MTH_TCP(1,4));
            app.YEditField.Value=round(MTH_TCP(2,4));
            app.ZEditField.Value=round(MTH_TCP(3,4));
            
            q_en_grados=app.q;
            for i=2:5
                q_en_grados(i)=app.q(i)*180/pi;
            end
            if app.conectado==1
                MoverRobotManual(q_en_grados,app.posicion_tornillo,app.velocidad_motor,app.velocidad_servo);
            end
            %actualizamos el registro de la posición de la pinza y el
            %tornillo
            app.posicion_tornillo=app.q(1);
            app.ActualizaSliders;
        end
        function ActualizaSliders(app)
            app.Q1Slider.Value=abs(app.q(1));
            giro2=app.q(2)*180/pi;
            if giro2<=30 && giro2>=-30
                app.Q2Slider.Value=giro2;
            else
                if giro2>30
                    app.Q2Slider.Value=30;
                else
                    app.Q2Slider.Value=-30;
                end
                    
            end
            giro3=app.q(3)*180/pi;
            if giro3<=180 && giro3>=-20
                app.Q3Slider.Value=giro3;
            else
                if giro3>180
                    app.Q3Slider.Value=180;
                else
                    app.Q3Slider.Value=-20;
                end
                    
            end
            giro4=app.q(4)*180/pi;
            if giro4<=120 && giro4>=-120
                app.Q4Slider.Value=giro4;
            else
                if giro4>120
                    app.Q4Slider.Value=120;
                else
                    app.Q4Slider.Value=-120;
                end
                    
            end
            
            giroTCP=app.q(5)*180/pi;
            if giroTCP<=145 && giroTCP>=-145
                app.Q5Slider.Value=giroTCP;
            else
                if giroTCP>145
                    app.Q5Slider.Value=145;
                else
                    app.Q5Slider.Value=-145;
                end
                    
            end
        end
        
        function Actualiza_Inversa(app)

            app.q=Calcula_Inversa(app.myRobot,app.q,app.Xinv,app.Yinv,app.Zinv,app.Angulo_tcp);
            app.Actualiza_Directa;
%             app.plotRobot;
%             q_en_grados=app.q;
%             for i=2:5
%                 q_en_grados(i)=app.q(i)*180/pi;
%             end
%             if app.conectado==1
%                 MoverRobotManual(q_en_grados,app.posicion_tornillo,app.velocidad_motor,app.velocidad_servo);
%             end
%             app.posicion_tornillo=app.q(1);
%             ActualizaSliders(app);
 
        end
        function Inversa_trayectoria(app)

            app.q=Calcula_Inversa(app.myRobot,app.q,app.X1EditField.Value,app.Y1EditField.Value,app.Z1EditField.Value,0);
            app.Actualiza_Directa;
%             app.plotRobot;
%             q_en_grados=app.q;
%             for i=2:5
%                 q_en_grados(i,1)=app.q(i,1)*180/pi;
%             end
%             if app.conectado==1
%                 MoverRobotManual(q_en_grados,app.posicion_tornillo,app.velocidad_motor,app.velocidad_servo);
%                 pause(5);
%             end
%             app.posicion_tornillo=app.q(1);
%             ActualizaSliders(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Iniciamos la posicion del robot enmodo home
            app.q = homeConfiguration(app.myRobot);
            app.q_mem1=app.q;
            app.q_mem2=app.q;
            app.q_mem3=app.q;
            app.q_mem4=app.q;
            app.velocidad_motor=500;
            app.velocidad_servo=30;
            plotRobot(app);
            %Se cargan los valores por defecto
            app.PORTNUM = 5;
            app.BAUDNUM = 1;
        end

        % Value changed function: PinzaSwitch
        function Pinza(app, event)
            %Leemos la posicion de la pinza;
            % Abierta->'Abierta'
            % Cerrada->'Cerrada'
            switch app.PinzaSwitch.Value
                 
                case 'Abierta'
                    AbrirPinza();
                case 'Cerrada'
                    CerrarPinza();
                otherwise
            end
         
        end

        % Value changed function: PuertoEditField
        function Cambia_Puerto(app, event)
            app.PORTNUM = app.PuertoEditField.Value;
            
        end

        % Value changed function: Baud_numEditField
        function Cambia_Baudnum(app, event)
            app.BAUDNUM = app.Baud_numEditField.Value;
            
        end

        % Value changed function: Q1Slider
        function Q1movido(app, event)
            app.q(1) = app.Q1Slider.Value;
            
            app.Actualiza_Directa;
            
        end

        % Value changed function: Q2Slider
        function Q2movido(app, event)
            app.q(2) = app.Q2Slider.Value*pi/180;
            app.Actualiza_Directa;
            
        end

        % Value changed function: Q3Slider
        function Q3movido(app, event)
            app.q(3) = app.Q3Slider.Value*pi/180;
            
           app.Actualiza_Directa;
            
        end

        % Value changed function: Q4Slider
        function Q4movido(app, event)
            app.q(4) = app.Q4Slider.Value*pi/180;
            
            app.Actualiza_Directa;
        end

        % Value changed function: Q5Slider
        function Q5movido(app, event)
            app.q(5) = app.Q5Slider.Value*pi/180;
            app.Actualiza_Directa;
            
        end

        % Value changed function: XinvEditField
        function XinvChanged(app, event)
            app.Xinv = app.XinvEditField.Value;
            Actualiza_Inversa(app);
        end

        % Value changed function: YinvEditField
        function YinvChanged(app, event)
            app.Yinv = app.YinvEditField.Value;
            Actualiza_Inversa(app);
        end

        % Value changed function: ZinvEditField
        function ZinvChanged(app, event)
            app.Zinv = app.ZinvEditField.Value;
            Actualiza_Inversa(app);
        end

        % Value changed function: Angulo_TCP
        function Angulo_TCP_Changed(app, event)
            app.Angulo_tcp = app.Angulo_TCP.Value*pi/180;
            Actualiza_Inversa(app);
        end

        % Value changed function: DesplegarCinematicaDirectaSwitch
        function DespliegaCD(app, event)

            if app.DesplegarCinematicaDirectaSwitch.Value=="On"
                for i = 1:5
                    eval(sprintf('app.Q%dLabel.Visible  ="on"', i))
                    eval(sprintf('app.Q%dSlider.Visible  ="on"', i))
                end
                app.Cinematica_Directa.Visible = 'on';
                app.Muestra_Posicion.Visible ='on';
                app.mm.Visible = 'on';
                app.mm_2.Visible = 'on';
                app.mm_3.Visible = 'on';
                app.Y1EditFieldLabel_2.Visible='on';
                app.Z1EditFieldLabel_2.Visible='on';
                app.X1EditFieldLabel_2.Visible='on';
                app.YEditField.Visible='on';
                app.XEditField.Visible='on';
                app.ZEditField.Visible='on';
                
                app.X1EditFieldLabel_6.Visible='off';
                app.X1EditFieldLabel_5.Visible='off';
                app.X1EditFieldLabel_4.Visible='off';
                app.X1EditField.Visible='off';
                app.Y1EditField.Visible='off';
                app.Z1EditField.Visible='off';
                app.trayectoria.Visible='off';
                app.X1EditFieldLabel_7.Visible='off';
                
                app.PickPlaceButton.Visible='off';
                app.Z2EditFieldLabel.Visible='off';
                app.X2EditFieldLabel.Visible='off';
                app.Y2EditFieldLabel.Visible='off';
                app.Z2EditField.Visible='off';
                app.X2EditField.Visible='off';
                app.Y2EditField.Visible='off';
                app.AplicaTrayectoriaButton.Visible='off';
                
                 app.X2CEditFieldLabel.Visible='off';
                app.Y2CEditFieldLabel.Visible='off';
                app.Z2CEditFieldLabel.Visible='off';
                app.X2CEditField.Visible='off';
                app.Y2CEditField.Visible='off';
                app.Z2CEditField.Visible='off';
                
            else
                for i = 1:5
                    eval(sprintf('app.Q%dLabel.Visible  ="off"', i))
                    eval(sprintf('app.Q%dSlider.Visible  ="off"', i))
                end
                app.Cinematica_Directa.Visible = 'off';
                app.Muestra_Posicion.Visible ='off';
                app.mm.Visible = 'off';
                app.mm_2.Visible = 'off';
                app.mm_3.Visible = 'off';
                app.Y1EditFieldLabel_2.Visible='off';
                app.Z1EditFieldLabel_2.Visible='off';
                app.X1EditFieldLabel_2.Visible='off';
                app.YEditField.Visible='off';
                app.XEditField.Visible='off';
                app.ZEditField.Visible='off';
                
                app.X1EditFieldLabel_6.Visible='on';
                app.X1EditFieldLabel_5.Visible='on';
                app.X1EditFieldLabel_4.Visible='on';
                app.X1EditField.Visible='on';
                app.Y1EditField.Visible='on';
                app.Z1EditField.Visible='on';
                app.trayectoria.Visible='on';
                app.X1EditFieldLabel_7.Visible='on';
                app.PickPlaceButton.Visible='on';
                
                app.PickPlaceButton.Visible='on';
                app.Z2EditFieldLabel.Visible='on';
                app.X2EditFieldLabel.Visible='on';
                app.Y2EditFieldLabel.Visible='on';
                app.Z2EditField.Visible='on';
                app.X2EditField.Visible='on';
                app.Y2EditField.Visible='on';
                app.AplicaTrayectoriaButton.Visible='on';
                
                 if app.trayectoria.Value==3
                    app.X2CEditFieldLabel.Visible='on';
                    app.Y2CEditFieldLabel.Visible='on';
                    app.Z2CEditFieldLabel.Visible='on';
                    app.X2CEditField.Visible='on';
                    app.Y2CEditField.Visible='on';
                    app.Z2CEditField.Visible='on';         
                end
                
            end
            
            
        end

        % Button pushed function: PickPlaceButton
        function PickAndPlace(app, event)
            
                if app.conectado==1
                  
                  q_pick=[0.18 0 -12 10 0];
                  q_reposo_inicio=[0 0 120 -120 0];
                  q_reposo=[0.18 0 120 -120 0];
                  q_transporte=[0.05 0 120 -120 0];
                  q_preplace=[0.05 -30 100 -90 0];
                  q_place=[0.05 -30 25 -30 0];

                  %Posicion transporte
                    app.q(1)=q_reposo_inicio(1,1);
                    for i=2:5
                        app.q(i)=q_reposo_inicio(1,i)*pi/180;
                    end
                    app.Actualiza_Directa;
                    app.posicion_tornillo=app.q(1);

                  %pre-pick
                  
                    %Pasamos los datos a grados
                    conf=moveJ_conf_v3(app.myRobot,2,0.1,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),0.250,0,0.520);
                    for i=2:5
                          app.q(i,1)=app.q(i,1)*180/pi;
                    end
                    
                    app.q=MoverRobot(app.q,conf,app.velocidad_motor,app.velocidad_servo,1,1);
                    %Devuelve el q en grados y mm
                    app.posicion_tornillo=app.q(1);
                  
                    
                  %pick
                    app.q(1)=q_pick(1,1);
                    for i=2:5
                        app.q(i)=q_pick(1,i)*pi/180;
                    end
                    app.Actualiza_Directa;
                    %cerrramos pinza
                    
                    pause(6);
                    CerrarPinza
                    pause(0.5);
                      
                  %pre_pick
                      conf=moveL_conf_v3(app.myRobot,2,0.1,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),0.25,0,0.520);
                      for i=2:5
                            app.q(i,1)=app.q(i,1)*180/pi;
                      end
                      
                      app.q=MoverRobot(app.q,conf,app.velocidad_motor,app.velocidad_servo,2,1);
                      app.posicion_tornillo=app.q(1);
                      
                      %pause(3);
                  %Reposo
                      app.q(1)=q_reposo(1,1);
                      for i=2:5
                        app.q(i)=q_reposo(1,i)*pi/180;
                      end
                      app.Actualiza_Directa;
                      

                  %Transporte
                      app.q(1)=q_transporte(1,1);
                      for i=2:5
                        app.q(i)=q_transporte(1,i)*pi/180;
                      end
                        
                      app.Actualiza_Directa;
                  
                  %Pre-place
                      app.q(1)=q_preplace(1,1);
                      for i=2:5
                        app.q(i)=q_preplace(1,i)*pi/180;
                      end
                      app.Actualiza_Directa;
                      
                  %place
                      app.q(1)=q_place(1,1);
                      for i=2:5
                        app.q(i)=q_place(1,i)*pi/180;
                      end
                      app.Actualiza_Directa;
                      
                      %Abrimos piza
                      pause(4);
                      AbrirPinza
                      %pause(2);
                      
                  %Pre-place
                  app.q(1)=q_preplace(1,1);
                  for i=2:5
                    app.q(i)=q_preplace(1,i)*pi/180;
                  end
                  app.Actualiza_Directa;
                  
                  %reposo
                   q_reposo=[0.05 0 120 -120 0];
                    app.q(1)=q_reposo(1,1);
                    for i=2:5
                        app.q(i)=q_reposo(1,i)*pi/180;
                    end
                    app.Actualiza_Directa;
                    disp('Fin tarea')
                end         
        end

        % Button pushed function: HOMEButton
        function HOME(app, event)
            
            q_home=[0 0 0 0 0];
            app.q(1)=q_home(1,1);
            for i=2:5
                app.q(i)=q_home(1,i)*pi/180;
            end
            app.Actualiza_Directa;
        end

        % Button pushed function: REPOSOButton
        function REPOSO(app, event)
            q_reposo=[0 0 120 -120 0];
            app.q(1)=q_reposo(1,1);
            for i=2:5
                app.q(i)=q_reposo(1,i)*pi/180;
            end
            app.Actualiza_Directa;
        end

        % Button pushed function: MEM1Button
        function MEM_1(app, event)
            %Guardamos la posición actual del robot
            switch app.modo
                case 'Memorizar'
                app.q_mem1=[app.q(1);app.q(2);app.q(3);app.q(4);app.q(5)];
                case 'Aplicar'
                app.q=app.q_mem1;
                app.Actualiza_Directa;
            end
        end

        % Value changed function: PosicionprefabricadaSwitch
        function ConmutaMemorizar(app, event)
            app.modo = app.PosicionprefabricadaSwitch.Value;
            switch app.modo
                case 'Memorizar'
                    app.MEM1Button.BackgroundColor=[0 1 1];
                    app.MEM2Button.BackgroundColor=[0 1 1];
                    app.MEM3Button.BackgroundColor=[0 1 1];
                    app.MEM4Button.BackgroundColor=[0 1 1];
                case 'Aplicar'
                    app.MEM1Button.BackgroundColor=[0 1 0];
                    app.MEM2Button.BackgroundColor=[0 1 0];
                    app.MEM3Button.BackgroundColor=[0 1 0];
                    app.MEM4Button.BackgroundColor=[0 1 0];
            end
            
        end

        % Button pushed function: MEM2Button
        function MEM_2(app, event)
            %Guardamos la posición actual del robot
            switch app.modo
                case 'Memorizar'
                app.q_mem2=[app.q(1);app.q(2);app.q(3);app.q(4);app.q(5)];
                
                case 'Aplicar'
               app.q=app.q_mem2;
                app.Actualiza_Directa;
            end
        end

        % Button pushed function: MEM3Button
        function MEM_3(app, event)
            %Guardamos la posición actual del robot
            switch app.modo
                case 'Memorizar'
                app.q_mem3=[app.q(1);app.q(2);app.q(3);app.q(4);app.q(5)];
                
                case 'Aplicar'
                app.q=app.q_mem3;
                app.Actualiza_Directa;
            end
        end

        % Button pushed function: MEM4Button
        function MEM_4(app, event)
     app.posicionTornilloQ1
     %Guardamos la posición actual del robot
            switch app.modo
                case 'Memorizar'
                app.q_mem4=[app.q(1);app.q(2);app.q(3);app.q(4);app.q(5)];
                
                case 'Aplicar'
                app.q=app.q_mem4;
                app.Actualiza_Directa;
            end
        end

        % Button pushed function: ConectarButton
        function ConectarDynamixel(app, event)
            
            app.conectado=InicioConexion(app.posicion_tornillo,app.PORTNUM, app.BAUDNUM,app.velocidad_servo,app.velocidad_motor);
            if app.conectado==1
                app.ESTADOLamp.Color=[0 1 0];
            else
                app.ESTADOLamp.Color=[1 0 0];
            end
            app.posicion_tornillo=0;
            app.q(1)=0;
            app.ActualizaSliders;
            app.plotRobot;
        end

        % Button pushed function: DesconectarButton
        function DesconectarDynamixel(app, event)
            app.conectado=disconnectDXL;
            app.ESTADOLamp.Color=[1 0 0];
        end

        % Value changed function: VelMotorEditField
        function Cambia_Motor(app, event)
            app.velocidad_motor = app.VelMotorEditField.Value;
        end

        % Value changed function: VelServoEditField
        function Cambia_Servo(app, event)
            app.velocidad_servo = app.VelServoEditField.Value;
        end

        % Button pushed function: AplicaTrayectoriaButton
        function AplicaTrayectoria(app, event)
            app.Inversa_trayectoria;
            pause(3);
            
            switch app.trayectoria.Value
                case 1
                    
                    %conf=moveJ_conf_v2(app.myRobot,app.q,1,0.1,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),app.X2EditField.Value,app.Y2EditField.Value,app.Z2EditField.Value);
                    conf=moveJ_conf_v3(app.myRobot,1,0.1,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),app.X2EditField.Value,app.Y2EditField.Value,app.Z2EditField.Value);
                    if app.conectado==1
                        for i=2:5
                            app.q(i)=app.q(i)*180/pi;
                        end
                        app.q=MoverRobot(app.q,conf,app.velocidad_motor,app.velocidad_servo,0,0);
                        app.posicion_tornillo=app.q(1);
                        
                        for i=2:5
                            app.q(i)=app.q(i)*pi/180;
                        end
                        app.ActualizaSliders;
                        app.plotRobot;
                    else
                        %Muestreamos la trayectoria
                        for i=1:length(conf)
                            for j=1:5
                                app.q(j)=conf(i,j);
                            end
                            app.ActualizaSliders;
                            app.plotRobot;
                            pause(0.005);
                        end
                    end
                        
                case 2
                    conf=moveL_conf_v3(app.myRobot,1,0.1,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),app.X2EditField.Value,app.Y2EditField.Value,app.Z2EditField.Value);
                    if app.conectado==1
                        for i=2:5
                            app.q(i)=app.q(i)*180/pi;
                        end
                        app.q=MoverRobot(app.q,conf,app.velocidad_motor,app.velocidad_servo,0,0);
                        app.posicion_tornillo=app.q(1);
                        
                    for i=2:5
                            app.q(i)=app.q(i)*pi/180;
                    end
                    app.ActualizaSliders;
                    app.plotRobot;    
                    else
                        %Muestreamos la trayectoria
                        for i=1:length(conf)
                            for j=1:5
                                app.q(j)=conf(i,j); 
                            end
                                app.ActualizaSliders;
                                app.plotRobot;
                                pause(0.005);
                           
                        end
                    end
                case 3
                    x2=app.X2CEditField.Value;
                    y2=app.Y2CEditField.Value;
                    z2=app.Z2CEditField.Value;
                    x3=app.X2EditField.Value;
                    y3=app.Y2EditField.Value;
                    z3=app.Z2EditField.Value;
                    
                    conf=moveC_conf(app.myRobot,app.q(1),app.q(2),app.q(3),app.q(4),app.q(5),x2,y2,z2,x3,y3,z3);
                    if app.conectado==1
                        for i=2:5
                            app.q(i)=app.q(i)*180/pi;
                        end
                        app.q=MoverRobot(app.q,conf,app.velocidad_motor,app.velocidad_servo,0,0);
                        app.posicion_tornillo=app.q(1);
                        for i=2:5
                            app.q(i)=app.q(i)*pi/180;
                        end   
                        app.ActualizaSliders;
                        app.plotRobot;
                        
                    else
                        %Muestreamos la trayectoria
                        for i=1:length(conf)
                            for j=1:5
                                app.q(j)=conf(i,j);
                                
                            end
                            app.ActualizaSliders;
                            app.plotRobot;
                            pause(0.05);
                        end
                    end
            end
        end

        % Value changed function: trayectoria
        function TrayectoriaCambiada(app, event)
            if app.trayectoria.Value==3
                
                app.X2CEditFieldLabel.Visible='on';
                app.Y2CEditFieldLabel.Visible='on';
                app.Z2CEditFieldLabel.Visible='on';
                app.X2CEditField.Visible='on';
                app.Y2CEditField.Visible='on';
                app.Z2CEditField.Visible='on';
            else
                app.X2CEditFieldLabel.Visible='off';
                app.Y2CEditFieldLabel.Visible='off';
                app.Z2CEditFieldLabel.Visible='off';
                app.X2CEditField.Visible='off';
                app.Y2CEditField.Visible='off';
                app.Z2CEditField.Visible='off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RM360')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.2 0.535];
            app.UIAxes.YLim = [-0.4 0.4];
            app.UIAxes.ZLim = [-0.4 0.66];
            app.Cinematica_Directa_5 = uilabel(app.UIFigure);
            app.Cinematica_Directa_5.Text = '';
            app.Cinematica_Directa_4 = uilabel(app.UIFigure);
            app.Cinematica_Directa_4.Text = '';
            app.Cinematica_Directa_3 = uilabel(app.UIFigure);
            app.Cinematica_Directa_3.Text = {'Memorizar '; 'poses'; ''};
            app.Cinematica_Directa_2 = uilabel(app.UIFigure);
            app.Cinematica_Directa_2.Text = {'Cinematica '; 'Inversa'; ''};
            app.Z1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.Z1EditFieldLabel_4.Text = 'Angulo TCP';
            app.Cinematica_Directa = uilabel(app.UIFigure);
            app.Cinematica_Directa.Visible = 'off';
            app.Cinematica_Directa.Text = {'Cinematica Directa'; ''};
            app.Muestra_Posicion = uilabel(app.UIFigure);
            app.Muestra_Posicion.Visible = 'off';
            app.Muestra_Posicion.Text = {'Muestra Posicion TCP'; ''};
            app.Angulo_TCP = uieditfield(app.UIFigure, 'numeric');
            app.Angulo_TCP.ValueChangedFcn = createCallbackFcn(app, @Angulo_TCP_Changed, true);
            app.X1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_3.Text = {'X'; ''};
            app.Z1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.Z1EditFieldLabel_3.Text = 'Z';
            app.Y1EditFieldLabel_3 = uilabel(app.UIFigure);
            app.Y1EditFieldLabel_3.Text = 'Y';
            app.XinvEditField = uieditfield(app.UIFigure, 'numeric');
            app.XinvEditField.ValueChangedFcn = createCallbackFcn(app, @XinvChanged, true);
            app.XinvEditField.Value = 0.19;
            app.ZinvEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZinvEditField.ValueChangedFcn = createCallbackFcn(app, @ZinvChanged, true);
            app.ZinvEditField.Value = 0.35;
            app.YinvEditField = uieditfield(app.UIFigure, 'numeric');
            app.YinvEditField.ValueChangedFcn = createCallbackFcn(app, @YinvChanged, true);
            app.X1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_2.Visible = 'off';
            app.X1EditFieldLabel_2.Text = 'X';
            app.Z1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.Z1EditFieldLabel_2.Visible = 'off';
            app.Z1EditFieldLabel_2.Text = 'Z';
            app.Y1EditFieldLabel_2 = uilabel(app.UIFigure);
            app.Y1EditFieldLabel_2.Visible = 'off';
            app.Y1EditFieldLabel_2.Text = 'Y';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Editable = 'off';
            app.XEditField.Visible = 'off';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.ZEditField.Visible = 'off';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.YEditField.Visible = 'off';
            app.MTHLabel = uilabel(app.UIFigure);
            app.MTHLabel.Text = 'MTH';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Rx'; 'Ry'; 'Rz'; 'd'};
            app.UITable.RowName = {};
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [0 0.18];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1movido, true);
            app.Q1Slider.Visible = 'off';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Visible = 'off';
            app.Q1Label.Text = 'Q1';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-30 30];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2movido, true);
            app.Q2Slider.Visible = 'off';
            app.Q2Label = uilabel(app.UIFigure);
            app.Q2Label.Visible = 'off';
            app.Q2Label.Text = 'Q2';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-20 180];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3movido, true);
            app.Q3Slider.Visible = 'off';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Visible = 'off';
            app.Q3Label.Text = 'Q3';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-120 120];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4movido, true);
            app.Q4Slider.Visible = 'off';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-145 145];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5movido, true);
            app.Q5Slider.Visible = 'off';
            app.Q5Label = uilabel(app.UIFigure);
            app.Q5Label.Visible = 'off';
            app.Q5Label.Text = 'Q5';
            app.trayectoria = uidropdown(app.UIFigure);
            app.trayectoria.Items = {'J', 'L', 'C'};
            app.trayectoria.ItemsData = [1 2 3];
            app.trayectoria.ValueChangedFcn = createCallbackFcn(app, @TrayectoriaCambiada, true);
            app.trayectoria.Value = 1;
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditField.Value = -0.125;
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 0.12;
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z1EditField.Value = 0.35;
            app.PinzaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PinzaSwitch.Items = {'Abierta', 'Cerrada'};
            app.PinzaSwitch.ValueChangedFcn = createCallbackFcn(app, @Pinza, true);
            app.PinzaSwitch.Value = 'Abierta';
            app.PinzaLabel = uilabel(app.UIFigure);
            app.PinzaLabel.Text = 'Pinza:';
            app.PuertoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuertoEditField.ValueChangedFcn = createCallbackFcn(app, @Cambia_Puerto, true);
            app.PuertoEditField.Value = 5;
            app.PuertoEditFieldLabel = uilabel(app.UIFigure);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.Baud_numEditField = uieditfield(app.UIFigure, 'numeric');
            app.Baud_numEditField.ValueChangedFcn = createCallbackFcn(app, @Cambia_Baudnum, true);
            app.Baud_numEditField.Value = 1;
            app.Baud_numEditFieldLabel = uilabel(app.UIFigure);
            app.Baud_numEditFieldLabel.Text = 'Baud_num';
            app.mm = uilabel(app.UIFigure);
            app.mm.Visible = 'off';
            app.mm.Text = {'mm'; ''};
            app.mm_2 = uilabel(app.UIFigure);
            app.mm_2.Visible = 'off';
            app.mm_2.Text = {'mm'; ''};
            app.mm_3 = uilabel(app.UIFigure);
            app.mm_3.Visible = 'off';
            app.mm_3.Text = {'mm'; ''};
            app.mm_4 = uilabel(app.UIFigure);
            app.mm_4.Text = {'m'; ''};
            app.mm_5 = uilabel(app.UIFigure);
            app.mm_5.Text = {'m'; ''};
            app.mm_6 = uilabel(app.UIFigure);
            app.mm_6.Text = {'m'; ''};
            app.mm_7 = uilabel(app.UIFigure);
            app.mm_7.Text = 'º';
            app.Q4Label = uilabel(app.UIFigure);
            app.Q4Label.Visible = 'off';
            app.Q4Label.Text = {'Q4'; ''};
            app.DesplegarCinematicaDirectaSwitchLabel = uilabel(app.UIFigure);
            app.DesplegarCinematicaDirectaSwitchLabel.Text = {'Desplegar'; 'Cinematica '; 'Directa'};
            app.DesplegarCinematicaDirectaSwitch = uiswitch(app.UIFigure, 'toggle');
            app.DesplegarCinematicaDirectaSwitch.ValueChangedFcn = createCallbackFcn(app, @DespliegaCD, true);
            app.PickPlaceButton = uibutton(app.UIFigure, 'push');
            app.PickPlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PickAndPlace, true);
            app.PickPlaceButton.Text = 'Pick&Place';
            app.X1EditFieldLabel_4 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_4.Text = 'X1';
            app.X1EditFieldLabel_5 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_5.Text = 'Y1';
            app.X1EditFieldLabel_6 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_6.Text = 'Z1';
            app.X1EditFieldLabel_7 = uilabel(app.UIFigure);
            app.X1EditFieldLabel_7.Text = 'Move';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOME, true);
            app.HOMEButton.Text = 'HOME';
            app.REPOSOButton = uibutton(app.UIFigure, 'push');
            app.REPOSOButton.ButtonPushedFcn = createCallbackFcn(app, @REPOSO, true);
            app.REPOSOButton.Text = {'REPOSO'; ''};
            app.MEM1Button = uibutton(app.UIFigure, 'push');
            app.MEM1Button.ButtonPushedFcn = createCallbackFcn(app, @MEM_1, true);
            app.MEM1Button.Text = 'MEM 1';
            app.MEM2Button = uibutton(app.UIFigure, 'push');
            app.MEM2Button.ButtonPushedFcn = createCallbackFcn(app, @MEM_2, true);
            app.MEM2Button.Text = 'MEM 2';
            app.MEM3Button = uibutton(app.UIFigure, 'push');
            app.MEM3Button.ButtonPushedFcn = createCallbackFcn(app, @MEM_3, true);
            app.MEM3Button.Text = 'MEM 3';
            app.MEM4Button = uibutton(app.UIFigure, 'push');
            app.MEM4Button.ButtonPushedFcn = createCallbackFcn(app, @MEM_4, true);
            app.MEM4Button.Text = 'MEM 4';
            app.PosicionprefabricadaLabel = uilabel(app.UIFigure);
            app.PosicionprefabricadaLabel.Text = 'Posicion prefabricada: ';
            app.PosicionprefabricadaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PosicionprefabricadaSwitch.Items = {'Memorizar', 'Aplicar'};
            app.PosicionprefabricadaSwitch.ValueChangedFcn = createCallbackFcn(app, @ConmutaMemorizar, true);
            app.PosicionprefabricadaSwitch.Value = 'Memorizar';
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarDynamixel, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.UIFigure, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarDynamixel, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.ESTADOLamp = uilamp(app.UIFigure);
            app.AutoresChristianSalanDanielDavidRedondoLabel = uilabel(app.UIFigure);
            app.AutoresChristianSalanDanielDavidRedondoLabel.Text = {'Taller de Robotica 2022 // Robot RM360'; ''};
            app.AutoresChristianSalanDanielHernndezDavidRedondoLabel = uilabel(app.UIFigure);
            app.AutoresChristianSalanDanielHernndezDavidRedondoLabel.Text = {'Autores:'; ''; 'Christian Salan '; 'Daniel Hernández'; 'David Redondo'};
            app.VelMotorEditFieldLabel = uilabel(app.UIFigure);
            app.VelMotorEditFieldLabel.Text = 'Vel Motor';
            app.VelMotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelMotorEditField.ValueChangedFcn = createCallbackFcn(app, @Cambia_Motor, true);
            app.VelMotorEditField.Value = 500;
            app.VelServoEditFieldLabel = uilabel(app.UIFigure);
            app.VelServoEditFieldLabel.Text = 'Vel Servo';
            app.VelServoEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelServoEditField.ValueChangedFcn = createCallbackFcn(app, @Cambia_Servo, true);
            app.VelServoEditField.Value = 30;
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.Value = 0.26;
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditField.Value = 0.17;
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditField.Value = 0.33;
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.AplicaTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.AplicaTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @AplicaTrayectoria, true);
            app.AplicaTrayectoriaButton.Text = {'Aplica'; 'Trayectoria'};
            app.X2CEditField = uieditfield(app.UIFigure, 'numeric');
            app.X2CEditField.Visible = 'off';
            app.X2CEditField.Value = 0.23;
            app.Y2CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2CEditField.Visible = 'off';
            app.Y2CEditField.Value = 0.1;
            app.Z2CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2CEditField.Visible = 'off';
            app.Z2CEditField.Value = 0.35;
            app.X2CEditFieldLabel = uilabel(app.UIFigure);
            app.X2CEditFieldLabel.Visible = 'off';
            app.X2CEditFieldLabel.Text = 'X1.5';
            app.Y2CEditFieldLabel = uilabel(app.UIFigure);
            app.Y2CEditFieldLabel.Visible = 'off';
            app.Y2CEditFieldLabel.Text = 'Y1.5';
            app.Z2CEditFieldLabel = uilabel(app.UIFigure);
            app.Z2CEditFieldLabel.Visible = 'off';
            app.Z2CEditFieldLabel.Text = 'Z1.5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end